classdef exam2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HogwartsHouseCupLeaderboardUIFigure  matlab.ui.Figure
        SelectaHouseButtonGroup  matlab.ui.container.ButtonGroup
        GryffindorButton         matlab.ui.control.RadioButton
        SlytherinButton          matlab.ui.control.RadioButton
        RavenclawButton          matlab.ui.control.RadioButton
        HufflepuffButton         matlab.ui.control.RadioButton
        PointsLabel              matlab.ui.control.Label
        PointField               matlab.ui.control.NumericEditField
        GainButton               matlab.ui.control.Button
        GryffindorLabel          matlab.ui.control.Label
        GryffindorPanel          matlab.ui.container.Panel
        Image                    matlab.ui.control.Image
        GScore                   matlab.ui.control.Label
        SlytherinPanel           matlab.ui.container.Panel
        Image_5                  matlab.ui.control.Image
        SScore                   matlab.ui.control.Label
        HufflepuffPanel          matlab.ui.container.Panel
        Image_6                  matlab.ui.control.Image
        HScore                   matlab.ui.control.Label
        RavenclawPanel_2         matlab.ui.container.Panel
        Image_7                  matlab.ui.control.Image
        RScore                   matlab.ui.control.Label
        LoseButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        selected
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.selected = 'G';
            app.GryffindorPanel.BackgroundColor = '#740001';
            app.SlytherinPanel.BackgroundColor = '#1A472A';
            app.HufflepuffPanel.BackgroundColor = '#FFD800';
            app.RavenclawPanel_2.BackgroundColor = '#0E1A40';
        end

        % Selection changed function: SelectaHouseButtonGroup
        function SelectaHouseButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectaHouseButtonGroup.SelectedObject;
            if selectedButton == app.GryffindorButton
                app.selected = 'G';
            end
            if selectedButton == app.SlytherinButton
                app.selected = 'S';
            end
            if selectedButton == app.HufflepuffButton
                app.selected = 'H';
            end
            if selectedButton == app.RavenclawButton
                app.selected = 'R';
            end
        end

        % Button pushed function: GainButton
        function GainButtonPushed(app, event)
            pointsToAdd = app.PointField.Value;
            if strcmpi(app.selected, 'G')
                pointsHad = str2double(app.GScore.Text);
                points = pointsToAdd + pointsHad;
                app.GScore.Text = sprintf("%d", points);
            end
            if strcmpi(app.selected, 'S')
                pointsHad = str2double(app.SScore.Text);
                points = pointsToAdd + pointsHad;
                app.SScore.Text = sprintf("%d", points);
            end
            if strcmpi(app.selected, 'H')
                pointsHad = str2double(app.HScore.Text);
                points = pointsToAdd + pointsHad;
                app.HScore.Text = sprintf("%d", points);
            end
            if strcmpi(app.selected, 'R')
                pointsHad = str2double(app.RScore.Text);
                points = pointsToAdd + pointsHad;
                app.RScore.Text = sprintf("%d", points);
            end
        end

        % Button pushed function: LoseButton
        function LoseButtonPushed(app, event)
            pointsToAdd = app.PointField.Value;
            if strcmpi(app.selected, 'G')
                pointsHad = str2double(app.GScore.Text);
                points = -pointsToAdd + pointsHad;
                if points < 0
                    points = 0;
                end
                app.GScore.Text = sprintf("%d", points);
            end
            if strcmpi(app.selected, 'S')
                pointsHad = str2double(app.SScore.Text);
                points = -pointsToAdd + pointsHad;
                if points < 0
                    points = 0;
                end
                app.SScore.Text = sprintf("%d", points);
            end
            if strcmpi(app.selected, 'H')
                pointsHad = str2double(app.HScore.Text);
                points = -pointsToAdd + pointsHad;
                if points < 0
                    points = 0;
                end
                app.HScore.Text = sprintf("%d", points);
            end
            if strcmpi(app.selected, 'R')
                pointsHad = str2double(app.RScore.Text);
                points = -pointsToAdd + pointsHad;
                if points < 0
                    points = 0;
                end
                app.RScore.Text = sprintf("%d", points);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HogwartsHouseCupLeaderboardUIFigure = uifigure('Visible', 'off');
            app.HogwartsHouseCupLeaderboardUIFigure.Name = 'Hogwarts House Cup Leaderboard';
            app.SelectaHouseButtonGroup = uibuttongroup(app.HogwartsHouseCupLeaderboardUIFigure);
            app.SelectaHouseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectaHouseButtonGroupSelectionChanged, true);
            app.SelectaHouseButtonGroup.Title = 'Select a House';
            app.GryffindorButton = uiradiobutton(app.SelectaHouseButtonGroup);
            app.GryffindorButton.Text = 'Gryffindor';
            app.GryffindorButton.Value = true;
            app.SlytherinButton = uiradiobutton(app.SelectaHouseButtonGroup);
            app.SlytherinButton.Text = 'Slytherin';
            app.RavenclawButton = uiradiobutton(app.SelectaHouseButtonGroup);
            app.RavenclawButton.Text = 'Ravenclaw';
            app.HufflepuffButton = uiradiobutton(app.SelectaHouseButtonGroup);
            app.HufflepuffButton.Text = 'Hufflepuff';
            app.PointsLabel = uilabel(app.HogwartsHouseCupLeaderboardUIFigure);
            app.PointsLabel.Text = 'Points';
            app.PointField = uieditfield(app.HogwartsHouseCupLeaderboardUIFigure, 'numeric');
            app.GainButton = uibutton(app.HogwartsHouseCupLeaderboardUIFigure, 'push');
            app.GainButton.ButtonPushedFcn = createCallbackFcn(app, @GainButtonPushed, true);
            app.GainButton.Text = 'Gain';
            app.GryffindorLabel = uilabel(app.HogwartsHouseCupLeaderboardUIFigure);
            app.GryffindorLabel.Text = 'Gryffindor';
            app.GryffindorPanel = uipanel(app.HogwartsHouseCupLeaderboardUIFigure);
            app.GryffindorPanel.Title = 'Gryffindor';
            app.Image = uiimage(app.GryffindorPanel);
            app.Image.ImageSource = 'gryffindor-crest.png';
            app.GScore = uilabel(app.GryffindorPanel);
            app.GScore.Text = '0';
            app.SlytherinPanel = uipanel(app.HogwartsHouseCupLeaderboardUIFigure);
            app.SlytherinPanel.Title = 'Slytherin';
            app.Image_5 = uiimage(app.SlytherinPanel);
            app.Image_5.ImageSource = 'slytherin-crests.png';
            app.SScore = uilabel(app.SlytherinPanel);
            app.SScore.Text = '0';
            app.HufflepuffPanel = uipanel(app.HogwartsHouseCupLeaderboardUIFigure);
            app.HufflepuffPanel.Title = 'Hufflepuff';
            app.Image_6 = uiimage(app.HufflepuffPanel);
            app.Image_6.ImageSource = 'hufflepuff-crest.png';
            app.HScore = uilabel(app.HufflepuffPanel);
            app.HScore.Text = '0';
            app.RavenclawPanel_2 = uipanel(app.HogwartsHouseCupLeaderboardUIFigure);
            app.RavenclawPanel_2.Title = 'Ravenclaw';
            app.Image_7 = uiimage(app.RavenclawPanel_2);
            app.Image_7.ImageSource = 'ravenclaw-crest.png';
            app.RScore = uilabel(app.RavenclawPanel_2);
            app.RScore.Text = '0';
            app.LoseButton = uibutton(app.HogwartsHouseCupLeaderboardUIFigure, 'push');
            app.LoseButton.ButtonPushedFcn = createCallbackFcn(app, @LoseButtonPushed, true);
            app.LoseButton.Text = 'Lose';
            % Show the figure after all components are created
            app.HogwartsHouseCupLeaderboardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exam2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HogwartsHouseCupLeaderboardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HogwartsHouseCupLeaderboardUIFigure)
        end
    end
end