classdef CommunicationWithMATLAB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LEDButtonGroup            matlab.ui.container.ButtonGroup
        OFFButton                 matlab.ui.control.ToggleButton
        ONButton                  matlab.ui.control.ToggleButton
        ButtonCounterLabel        matlab.ui.control.Label
        CounterLabel              matlab.ui.control.Label
        ServoSliderLabel          matlab.ui.control.Label
        ServoSlider               matlab.ui.control.Slider
        ServoVlaueEditFieldLabel  matlab.ui.control.Label
        ServoVlaueEditField       matlab.ui.control.EditField
        SendServoValueButton      matlab.ui.control.Button
        Image                     matlab.ui.control.Image
    end

    
    properties (Access = private)
        counter = 0;
        serialObj % Description
    end
    
    methods (Access = private)
        
        function serialObjCallback(app, serialObj, eventData)
            message = strtrim(readline(serialObj))
            
            if strcmpi(message, "B")
                app.counter = app.counter + 1;
                app.CounterLabel.Text = sprintf("Count = %d", app.counter);
            end
            
            messageArray = split(message)
            if strcmpi(messageArray(1), "X")
                xValue = str2double(messageArray(2));
                app.Image.Position(1) = xValue/2;
            end
            if strcmpi(messageArray(1), "Y")
                yValue = str2double(messageArray(2));
                app.Image.Position(2) = yValue/2;
            end

        end
        
        function sendServoValue(app, degrees)
            degrees = round(degrees);
            command = sprintf("SERVO %d", degrees);
            fprintf(sprintf("Sending: ") + command);
            writeline(app.serialObj, command);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.serialObj = serialport("COM5", 9600);
            flush(app.serialObj);
            configureCallback(app.serialObj, "terminator", @app.serialObjCallback);
        end

        % Callback function
        function LEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.LEDButtonGroup.Position;
            
            if selectedButton == app.OFFButton
                writeline(app.serialObj, "LED OFF");
                fprintf("on");
            end
            if selectedButton == app.ONButton
                writeline(app.serialObj, "LED ON");
            end
        end

        % Selection changed function: LEDButtonGroup
        function LEDButtonGroupSelectionChanged2(app, event)
            selectedButton = app.LEDButtonGroup.SelectedObject;
            if selectedButton == app.OFFButton
                writeline(app.serialObj, "LED OFF");
            end
            if selectedButton == app.ONButton
                writeline(app.serialObj, "LED ON");
            end
        end

        % Value changed function: ServoSlider
        function ServoSliderValueChanged(app, event)
            value = app.ServoSlider.Value;
            app.sendServoValue(value);

        end

        % Value changing function: ServoSlider
        function ServoSliderValueChanging(app, event)
            changingValue = event.Value;
            app.sendServoValue(changingValue);
        end

        % Button pushed function: SendServoValueButton
        function SendServoValueButtonPushed(app, event)
            valueAsStr = app.ServoVlaueEditField.Value;
            app.sendServoValue(str2double(valueAsStr));
            app.ServoSlider.Value = str2double(valueAsStr);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEDButtonGroup = uibuttongroup(app.UIFigure);
            app.LEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LEDButtonGroupSelectionChanged2, true);
            app.LEDButtonGroup.Title = 'LED';
            app.OFFButton = uitogglebutton(app.LEDButtonGroup);
            app.OFFButton.Text = 'OFF';
            app.OFFButton.Value = true;
            app.ONButton = uitogglebutton(app.LEDButtonGroup);
            app.ONButton.Text = 'ON';
            app.ButtonCounterLabel = uilabel(app.UIFigure);
            app.ButtonCounterLabel.Text = 'Button Counter:';
            app.CounterLabel = uilabel(app.UIFigure);
            app.CounterLabel.Text = 'Counter = 0';
            app.ServoSliderLabel = uilabel(app.UIFigure);
            app.ServoSliderLabel.Text = {'Servo'; 'Slider'};
            app.ServoSlider = uislider(app.UIFigure);
            app.ServoSlider.Limits = [0 180];
            app.ServoSlider.ValueChangedFcn = createCallbackFcn(app, @ServoSliderValueChanged, true);
            app.ServoSlider.ValueChangingFcn = createCallbackFcn(app, @ServoSliderValueChanging, true);
            app.ServoVlaueEditFieldLabel = uilabel(app.UIFigure);
            app.ServoVlaueEditFieldLabel.Text = {'Servo'; 'Vlaue'; ''};
            app.ServoVlaueEditField = uieditfield(app.UIFigure, 'text');
            app.ServoVlaueEditField.Value = '0';
            app.SendServoValueButton = uibutton(app.UIFigure, 'push');
            app.SendServoValueButton.ButtonPushedFcn = createCallbackFcn(app, @SendServoValueButtonPushed, true);
            app.SendServoValueButton.Text = 'Send Servo Value';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'mhw-rathalos_icon.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CommunicationWithMATLAB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end