classdef HelloButton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HELLOBUTTONUIFigure  matlab.ui.Figure
        ResetButton          matlab.ui.control.Button
        DecrementButton      matlab.ui.control.Button
        IncrementButton      matlab.ui.control.Button
        CountLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        counter = 0;
        serialObj;
    end
    
    methods (Access = private)
        
        function updateView(app)
            fprintf("Count = %d\n", app.counter);
            app.CountLabel.Text = sprintf("Count = %d", app.counter);
        end
        
        function serialObjCallback(app, serialObj, eventData)
            %fprintf("Hit the callback!\n");
           message = strtrim(readline(serialObj))
           if strcmpi(message, "B")
               app.counter = app.counter + 1;
               app.updateView();
           end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.serialObj = serialport("COM5",9600);
            flush(app.serialObj);
            configureCallback(app.serialObj, "terminator", @app.serialObjCallback);
        end

        % Callback function
        function PressMeButtonPushed(app, event)
            %    fprintf("You pressed the button! \n");
            %    app.PressMeButton.BackgroundColor = 'y';
            %    app.HELLOBUTTONUIFigure.Color = 'r';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % update a counter
            % update display
            app.counter = 0;
            app.updateView();
        end

        % Button pushed function: DecrementButton
        function DecrementButtonPushed(app, event)
            app.counter = app.counter - 1;
            app.updateView();
            writeline(app.serialObj, "LED OFF");
        end

        % Button pushed function: IncrementButton
        function IncrementButtonPushed(app, event)
            app.counter = app.counter + 1;
            app.updateView();
            writeline(app.serialObj, "LED ON");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HELLOBUTTONUIFigure = uifigure('Visible', 'off');
            app.HELLOBUTTONUIFigure.Name = 'HELLO BUTTON';
            app.ResetButton = uibutton(app.HELLOBUTTONUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DecrementButton = uibutton(app.HELLOBUTTONUIFigure, 'push');
            app.DecrementButton.ButtonPushedFcn = createCallbackFcn(app, @DecrementButtonPushed, true);
            app.DecrementButton.Text = 'Decrement';
            app.IncrementButton = uibutton(app.HELLOBUTTONUIFigure, 'push');
            app.IncrementButton.ButtonPushedFcn = createCallbackFcn(app, @IncrementButtonPushed, true);
            app.IncrementButton.Text = 'Increment';
            app.CountLabel = uilabel(app.HELLOBUTTONUIFigure);
            app.CountLabel.Text = 'Count = 0';
            % Show the figure after all components are created
            app.HELLOBUTTONUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HelloButton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HELLOBUTTONUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HELLOBUTTONUIFigure)
        end
    end
end